classdef shy_num_guess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NewGameButton                   matlab.ui.control.Button
        LoserEditField                  matlab.ui.control.EditField
        LoserEditFieldLabel             matlab.ui.control.Label
        WinnerEditField                 matlab.ui.control.EditField
        WinnerEditFieldLabel            matlab.ui.control.Label
        SystemScoreEditField            matlab.ui.control.NumericEditField
        vsLabel_3                       matlab.ui.control.Label
        SystemScoreEditFieldLabel       matlab.ui.control.Label
        UserScoreEditField              matlab.ui.control.NumericEditField
        UserScoreEditFieldLabel         matlab.ui.control.Label
        FinalScoresLabel                matlab.ui.control.Label
        GamePlay_ResultsLabel           matlab.ui.control.Label
        SystemCurrentScoreEditField     matlab.ui.control.NumericEditField
        vsLabel_2                       matlab.ui.control.Label
        SystemCurrentScoreEditFieldLabel  matlab.ui.control.Label
        UserCurrentScoreEditField       matlab.ui.control.NumericEditField
        UserCurrentScoreEditFieldLabel  matlab.ui.control.Label
        ScoreCardLabel                  matlab.ui.control.Label
        GamePlayPanel                   matlab.ui.container.Panel
        vsLabel                         matlab.ui.control.Label
        EXITViewScoreButton             matlab.ui.control.Button
        ContinuetonextroundButton       matlab.ui.control.Button
        SystemGuessEditField            matlab.ui.control.NumericEditField
        UserGuessEditField              matlab.ui.control.NumericEditField
        GuessButton                     matlab.ui.control.Button
        WarningEditField                matlab.ui.control.EditField
        EnteraRandomNumberSpinner       matlab.ui.control.Spinner
        RoundEditField                  matlab.ui.control.NumericEditField
        SystemGuessEditFieldLabel       matlab.ui.control.Label
        UserGuessEditFieldLabel         matlab.ui.control.Label
        ofRoundsEditField_4Label        matlab.ui.control.Label
        WarningEditFieldLabel           matlab.ui.control.Label
        EnteraRandomNumberSpinnerLabel  matlab.ui.control.Label
        RoundEditFieldLabel             matlab.ui.control.Label
        StartGameButton                 matlab.ui.control.Button
        MaxGuessingRangeSpinner         matlab.ui.control.Spinner
        MaxGuessingRangeSpinnerLabel    matlab.ui.control.Label
        ofRoundsSpinner                 matlab.ui.control.Spinner
        ofRoundsSpinnerLabel            matlab.ui.control.Label
        UmmLetsGuessaNumLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        r double;
        m double;
        n2=NaN;
        n3=NaN;
        s1=0; %double;
        s2=0; %double;
        j double;
         % Description
    end
    
    properties (Access = public)
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ofRoundsSpinner
        function ofRoundsSpinnerValueChanged(app, event)
            app.r = app.ofRoundsSpinner.Value;
            app.MaxGuessingRangeSpinnerLabel.Enable="on";
            app.MaxGuessingRangeSpinner.Enable="on";
            disp("max= "+app.m);
            app.StartGameButton.Enable="on";
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.ofRoundsSpinner.Enable="off";
            app.MaxGuessingRangeSpinner.Enable="off";
            app.m=app.MaxGuessingRangeSpinner.Value;
            app.StartGameButton.Enable="off";
            app.GamePlayPanel.Enable="on";
            app.j=1;
            app.RoundEditFieldLabel.Enable="on";
            app.RoundEditField.Enable="on";
            app.RoundEditField.Value=app.j;
            app.EnteraRandomNumberSpinnerLabel.Enable="on";
            app.EnteraRandomNumberSpinner.Enable="on";
        end

        % Callback function: EnteraRandomNumberSpinner, GamePlayPanel
        function EnteraRandomNumberSpinnerValueChanged(app, event)
            app.n2=app.EnteraRandomNumberSpinner.Value;
            app.WarningEditFieldLabel.Enable="on";
            app.WarningEditField.Enable="on";
           
                app.UserGuessEditField.Enable="on";
                app.vsLabel.Enable="on";
                %k=app.m;
                disp(app.n2);
                disp(app.m);

                if (app.n2<app.m)
                    app.WarningEditFieldLabel.Enable="on";
                    app.WarningEditField.Enable="on";
                    app.WarningEditField.Value="None !!";
                    app.UserGuessEditFieldLabel.Enable="on";
                    app.UserGuessEditField.Value=app.n2;
                    app.GuessButton.Enable="on";
                elseif app.n2==app.m
                    app.WarningEditFieldLabel.Enable="on";
                    app.WarningEditField.Enable="on";
                    app.WarningEditField.Value="None !!";
                    app.UserGuessEditFieldLabel.Enable="on";
                    app.UserGuessEditField.Value=app.n2;
                    app.GuessButton.Enable="on";
                else 
                    app.GuessButton.Enable="off";
                    app.UserGuessEditFieldLabel.Enable="on";
                    app.UserGuessEditField.Value=app.n2;
                    app.WarningEditField.Value="Enter Within Max set range !!";
                    return;
                end
            
        end

        % Button pushed function: GuessButton
        function GuessButtonPushed(app, event)
            app.EnteraRandomNumberSpinner.Enable="off";
            app.GuessButton.Enable="off";
            app.n3=randi([0 app.m]);
            app.SystemGuessEditFieldLabel.Enable="on";
            app.SystemGuessEditField.Enable="on";
            app.SystemGuessEditField.Value=app.n3;
            %app.s1=0;
            %app.s2=0;
            if (app.n2>app.n3)
                app.s1=app.s1+1;
            elseif (app.n2==app.n3)
                app.s2=app.s2+1;
                app.s1=app.s1+1;
                %t=t+1;
            else
                app.s2=app.s2+1;
            end
            %t=t+1;
            app.vsLabel_2.Enable="on";
            app.UserCurrentScoreEditField.Enable="on";
            app.UserCurrentScoreEditField.Value=app.s1;
            app.SystemCurrentScoreEditField.Enable="on";
            app.SystemCurrentScoreEditField.Value=app.s2;
            app.r=app.r-1;
            if app.r>0
                
                app.ContinuetonextroundButton.Enable="on";
                app.EXITViewScoreButton.Enable="on";
            else
                app.FinalScoresLabel.Enable="on";
                app.UserScoreEditField.Enable="on";
                app.vsLabel_3.Enable="on";
                app.SystemScoreEditField.Enable="on";
                app.WinnerEditField.Enable="on";
                app.LoserEditField.Enable="on";
                app.UserScoreEditField.Value=app.s1;
                app.SystemScoreEditField.Value=app.s2;
                if(app.s1>app.s2)
                    app.WinnerEditField.Value="User";
                    app.LoserEditField.Value="System";
                elseif(app.s1==app.s2)
                    app.WinnerEditField.Value="Tie";
                    app.LoserEditField.Value="Tie";
                else
                    app.WinnerEditField.Value="System";
                    app.LoserEditField.Value="User";
                end
                return;
            end
        end

        % Button pushed function: ContinuetonextroundButton
        function ContinuetonextroundButtonPushed(app, event)
            app.ContinuetonextroundButton.Enable="off";
            app.EXITViewScoreButton.Enable="off";
            if(app.r>0)
                app.j=app.j+1;
                app.EnteraRandomNumberSpinner.Enable="on";
            else
                return;
            end
        end

        % Button pushed function: EXITViewScoreButton
        function EXITViewScoreButtonPushed(app, event)
            app.ContinuetonextroundButton.Enable="off";
            app.EXITViewScoreButton.Enable="off";
            app.EnteraRandomNumberSpinner.Enable="off";
            app.ofRoundsSpinner.Enable="off";
            app.FinalScoresLabel.Enable="on";
            app.UserScoreEditField.Enable="on";
            app.vsLabel_3.Enable="on";
            app.SystemScoreEditField.Enable="on";
            app.WinnerEditField.Enable="on";
            app.LoserEditField.Enable="on";
            app.UserScoreEditField.Value=app.s1;
            app.SystemScoreEditField.Value=app.s2;
            if(app.s1>app.s2)
                app.WinnerEditField.Value="User";
                app.LoserEditField.Value="Loser";
            elseif(app.s1==app.s2)
                app.WinnerEditField.Value="Tie";
                app.LoserEditField.Value="Tie";
            else
                app.WinnerEditField.Value="Loser";
                app.LoserEditField.Value="User";
            end
            app.NewGameButton.Enable="on";
            return;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.UmmLetsGuessaNumLabel = uilabel(app.UIFigure);
            app.UmmLetsGuessaNumLabel.Text = 'Umm... Let''s Guess a Num !!';
            app.ofRoundsSpinnerLabel = uilabel(app.UIFigure);
            app.ofRoundsSpinnerLabel.Text = '# of Rounds :';
            app.ofRoundsSpinner = uispinner(app.UIFigure);
            app.ofRoundsSpinner.Limits = [1 Inf];
            app.ofRoundsSpinner.RoundFractionalValues = 'on';
            app.ofRoundsSpinner.ValueChangedFcn = createCallbackFcn(app, @ofRoundsSpinnerValueChanged, true);
            app.ofRoundsSpinner.Placeholder = 'r>0';
            app.ofRoundsSpinner.Value = 1;
            app.MaxGuessingRangeSpinnerLabel = uilabel(app.UIFigure);
            app.MaxGuessingRangeSpinnerLabel.Enable = 'off';
            app.MaxGuessingRangeSpinnerLabel.Text = 'Max Guessing Range:';
            app.MaxGuessingRangeSpinner = uispinner(app.UIFigure);
            app.MaxGuessingRangeSpinner.Limits = [1 Inf];
            app.MaxGuessingRangeSpinner.RoundFractionalValues = 'on';
            app.MaxGuessingRangeSpinner.Enable = 'off';
            app.MaxGuessingRangeSpinner.Placeholder = '+ve';
            app.MaxGuessingRangeSpinner.Value = 1;
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Enable = 'off';
            app.StartGameButton.Text = 'Start Game';
            app.GamePlayPanel = uipanel(app.UIFigure);
            app.GamePlayPanel.Enable = 'off';
            app.GamePlayPanel.Title = '-----<GamePlay>-----';
            app.GamePlayPanel.SizeChangedFcn = createCallbackFcn(app, @EnteraRandomNumberSpinnerValueChanged, true);
            app.RoundEditFieldLabel = uilabel(app.GamePlayPanel);
            app.RoundEditFieldLabel.Enable = 'off';
            app.RoundEditFieldLabel.Text = 'Round # :';
            app.EnteraRandomNumberSpinnerLabel = uilabel(app.GamePlayPanel);
            app.EnteraRandomNumberSpinnerLabel.Enable = 'off';
            app.EnteraRandomNumberSpinnerLabel.Text = 'Enter a Random Number :';
            app.WarningEditFieldLabel = uilabel(app.GamePlayPanel);
            app.WarningEditFieldLabel.Enable = 'off';
            app.WarningEditFieldLabel.Text = 'Warning :';
            app.ofRoundsEditField_4Label = uilabel(app.GamePlayPanel);
            app.ofRoundsEditField_4Label.Enable = 'off';
            app.ofRoundsEditField_4Label.Text = 'System :';
            app.UserGuessEditFieldLabel = uilabel(app.GamePlayPanel);
            app.UserGuessEditFieldLabel.Enable = 'off';
            app.UserGuessEditFieldLabel.Text = 'User Guess  :';
            app.SystemGuessEditFieldLabel = uilabel(app.GamePlayPanel);
            app.SystemGuessEditFieldLabel.Enable = 'off';
            app.SystemGuessEditFieldLabel.Text = 'System Guess:';
            app.RoundEditField = uieditfield(app.GamePlayPanel, 'numeric');
            app.RoundEditField.AllowEmpty = 'on';
            app.RoundEditField.Editable = 'off';
            app.RoundEditField.Enable = 'off';
            app.RoundEditField.Value = [];
            app.EnteraRandomNumberSpinner = uispinner(app.GamePlayPanel);
            app.EnteraRandomNumberSpinner.Limits = [0 Inf];
            app.EnteraRandomNumberSpinner.RoundFractionalValues = 'on';
            app.EnteraRandomNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @EnteraRandomNumberSpinnerValueChanged, true);
            app.EnteraRandomNumberSpinner.Enable = 'off';
            app.EnteraRandomNumberSpinner.Placeholder = '+ve no.';
            app.WarningEditField = uieditfield(app.GamePlayPanel, 'text');
            app.WarningEditField.Editable = 'off';
            app.WarningEditField.Enable = 'off';
            app.GuessButton = uibutton(app.GamePlayPanel, 'push');
            app.GuessButton.ButtonPushedFcn = createCallbackFcn(app, @GuessButtonPushed, true);
            app.GuessButton.Enable = 'off';
            app.GuessButton.Text = 'Guess';
            app.UserGuessEditField = uieditfield(app.GamePlayPanel, 'numeric');
            app.UserGuessEditField.Editable = 'off';
            app.UserGuessEditField.Enable = 'off';
            app.SystemGuessEditField = uieditfield(app.GamePlayPanel, 'numeric');
            app.SystemGuessEditField.Editable = 'off';
            app.SystemGuessEditField.Enable = 'off';
            app.ContinuetonextroundButton = uibutton(app.GamePlayPanel, 'push');
            app.ContinuetonextroundButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuetonextroundButtonPushed, true);
            app.ContinuetonextroundButton.Enable = 'off';
            app.ContinuetonextroundButton.Text = 'Continue to next round';
            app.EXITViewScoreButton = uibutton(app.GamePlayPanel, 'push');
            app.EXITViewScoreButton.ButtonPushedFcn = createCallbackFcn(app, @EXITViewScoreButtonPushed, true);
            app.EXITViewScoreButton.Enable = 'off';
            app.EXITViewScoreButton.Text = 'EXIT & View Score';
            app.vsLabel = uilabel(app.GamePlayPanel);
            app.vsLabel.Enable = 'off';
            app.vsLabel.Text = 'v/s';
            app.ScoreCardLabel = uilabel(app.UIFigure);
            app.ScoreCardLabel.Text = '-----<ScoreCard>-----';
            app.UserCurrentScoreEditFieldLabel = uilabel(app.UIFigure);
            app.UserCurrentScoreEditFieldLabel.Enable = 'off';
            app.UserCurrentScoreEditFieldLabel.Text = 'User  Current Score:';
            app.UserCurrentScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.UserCurrentScoreEditField.AllowEmpty = 'on';
            app.UserCurrentScoreEditField.Editable = 'off';
            app.UserCurrentScoreEditField.Enable = 'off';
            app.SystemCurrentScoreEditFieldLabel = uilabel(app.UIFigure);
            app.SystemCurrentScoreEditFieldLabel.Enable = 'off';
            app.SystemCurrentScoreEditFieldLabel.Text = 'System Current Score:';
            app.vsLabel_2 = uilabel(app.UIFigure);
            app.vsLabel_2.Enable = 'off';
            app.vsLabel_2.Text = 'v/s';
            app.SystemCurrentScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.SystemCurrentScoreEditField.AllowEmpty = 'on';
            app.SystemCurrentScoreEditField.Editable = 'off';
            app.SystemCurrentScoreEditField.Enable = 'off';
            app.GamePlay_ResultsLabel = uilabel(app.UIFigure);
            app.GamePlay_ResultsLabel.Text = '-----<GamePlay_Results>-----';
            app.FinalScoresLabel = uilabel(app.UIFigure);
            app.FinalScoresLabel.Enable = 'off';
            app.FinalScoresLabel.Text = 'Final Scores...';
            app.UserScoreEditFieldLabel = uilabel(app.UIFigure);
            app.UserScoreEditFieldLabel.Enable = 'off';
            app.UserScoreEditFieldLabel.Text = 'User  Score:';
            app.UserScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.UserScoreEditField.AllowEmpty = 'on';
            app.UserScoreEditField.Editable = 'off';
            app.UserScoreEditField.Enable = 'off';
            app.UserScoreEditField.Value = 1;
            app.SystemScoreEditFieldLabel = uilabel(app.UIFigure);
            app.SystemScoreEditFieldLabel.Enable = 'off';
            app.SystemScoreEditFieldLabel.Text = 'System  Score:';
            app.vsLabel_3 = uilabel(app.UIFigure);
            app.vsLabel_3.Enable = 'off';
            app.vsLabel_3.Text = 'v/s';
            app.SystemScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.SystemScoreEditField.AllowEmpty = 'on';
            app.SystemScoreEditField.Editable = 'off';
            app.SystemScoreEditField.Enable = 'off';
            app.WinnerEditFieldLabel = uilabel(app.UIFigure);
            app.WinnerEditFieldLabel.Enable = 'off';
            app.WinnerEditFieldLabel.Text = 'Winner';
            app.WinnerEditField = uieditfield(app.UIFigure, 'text');
            app.WinnerEditField.Editable = 'off';
            app.WinnerEditField.Enable = 'off';
            app.LoserEditFieldLabel = uilabel(app.UIFigure);
            app.LoserEditFieldLabel.Enable = 'off';
            app.LoserEditFieldLabel.Text = 'Loser';
            app.LoserEditField = uieditfield(app.UIFigure, 'text');
            app.LoserEditField.Editable = 'off';
            app.LoserEditField.Enable = 'off';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.Enable = 'off';
            app.NewGameButton.Text = 'New Game';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shy_num_guess

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end